classdef rtSgramWithAcqusition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        STARTButton            matlab.ui.control.Button
        STOPButton             matlab.ui.control.Button
        QUITButton             matlab.ui.control.Button
        RUNLampLabel           matlab.ui.control.Label
        RUNLamp                matlab.ui.control.Lamp
        OVRLampLabel           matlab.ui.control.Label
        OVRLamp                matlab.ui.control.Lamp
        RealtimespectrogramwithAcquisitionSP3ARKLabel  matlab.ui.control.Label
        TypeDropDownLabel      matlab.ui.control.Label
        TypeDropDown           matlab.ui.control.DropDown
        AboutButton            matlab.ui.control.Button
        CMapDropDownLabel      matlab.ui.control.Label
        CMapDropDown           matlab.ui.control.DropDown
        CALIBRATIONPanel       matlab.ui.container.Panel
        TestsignalstartButton  matlab.ui.control.Button
        dBButton70             matlab.ui.control.Button
        dBButton80             matlab.ui.control.Button
        Lamp70dB               matlab.ui.control.Lamp
        Lamp80rab              matlab.ui.control.Lamp
        RESETButton            matlab.ui.control.Button
        CalLevelLabel          matlab.ui.control.Label
        SAVEButton             matlab.ui.control.Button
        PLAYButton             matlab.ui.control.Button
        RightPanel             matlab.ui.container.Panel
        WidthmsSliderLabel     matlab.ui.control.Label
        WidthmsSlider          matlab.ui.control.Slider
        PANkHzSliderLabel      matlab.ui.control.Label
        PANkHzSlider           matlab.ui.control.Slider
        MAGSliderLabel         matlab.ui.control.Label
        MAGSlider              matlab.ui.control.Slider
        RangedBSliderLabel     matlab.ui.control.Label
        RangedBSlider          matlab.ui.control.Slider
        LowLdBSliderLabel      matlab.ui.control.Label
        LowLdBSlider           matlab.ui.control.Slider
        WaveformMonitor        matlab.ui.control.UIAxes
        SpectrumMonitor        matlab.ui.control.UIAxes
        SgramAxis              matlab.ui.control.UIAxes
        WaveWholeAxis          matlab.ui.control.UIAxes
        LevelBarAxis           matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SignalData % Description
    end
   
    methods (Access = private)
        
        function results = initializeAudioInput(app)
            results = [];
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
            f = msgbox({'Please select the working directory.'; ...
                'Then, please select the audio device.'}, 'Initialization','help');
            app.SignalData.workingDirectory = string(uigetdir);
            fs = 44100;
            %bufferLength = 1024;
            recorderObj = audioPlayerRecorder;
            devices = getAudioDevices(recorderObj);
            [deviceID,tf] = listdlg('ListString', ...
                [devices, {"Different in and out"}], 'SelectionMode','single');
            if tf && deviceID > length(devices)
                recorderObj = audioDeviceReader;
                devicesIn = getAudioDevices(recorderObj);
                playerObj = audioDeviceWriter;
                devicesOut = getAudioDevices(playerObj);
                [InputdeviceID,tf] = listdlg('ListString',devicesIn, 'SelectionMode','single');
                if ~tf
                    close(app.UIFigure);
                end
                [OutputdeviceID,tf] = listdlg('ListString',devicesOut, 'SelectionMode','single');
                if ~tf
                    close(app.UIFigure);
                end
                app.SignalData.audioMode = 'independent';
            else
                app.SignalData.audioMode = 'simultaneous';
            end
            if ~tf
                close(app.UIFigure);
            else
                logFileName = "rtSgAcqLog" + datestr(now, 30) + ".txt";
                app.SignalData.logFileFid = ...
                    fopen(app.SignalData.workingDirectory + "/" + logFileName, "a");
                switch app.SignalData.audioMode
                    case 'simultaneous'
                        app.SignalData.Device = devices{deviceID};
                        app.SignalData.recorder = audioPlayerRecorder(fs,"RecorderChannelMapping", 1, ...
                            "BitDepth", "24-bit integer", "Device", devices{deviceID});
                        %bufferLength, ...
                        %    "ChannelMapping", 1, "BitDepth","24-bit integer", ...
                        %    "Device", devices{deviceID});
                        bufferLength = get(app.SignalData.recorder, "BufferSize");
                        app.SignalData.samplingFrequency = fs;
                        app.SignalData.bufferSize = bufferLength;
                        app.SignalData.Driver = 'simultaneousIO';
                    case 'independent'
                        bufferLength = 1024;
                        app.SignalData.Device = devicesIn{InputdeviceID};
                        app.SignalData.recorder = audioDeviceReader(fs, ...
                            "BitDepth", "24-bit integer", "SamplesPerFrame", bufferLength, ...
                            "ChannelMapping", 1, "BitDepth","24-bit integer", ...
                            "Device", devicesIn{InputdeviceID});
                        app.SignalData.samplingFrequency = fs;
                        app.SignalData.bufferSize = bufferLength;
                        app.SignalData.Driver = 'independentIO';
                        app.SignalData.player = audioDeviceWriter(fs, ...
                            "BitDepth","24-bit integer","BufferSize", bufferLength, ...
                            "Device", devicesOut{OutputdeviceID});
                end
                %--- log test condition
                switch app.SignalData.audioMode
                    case 'simultaneous'
                        fprintf(app.SignalData.logFileFid, 'Computer: %s\n', computer);
                        fprintf(app.SignalData.logFileFid, 'Device: %s\n', devices{deviceID});
                        fprintf(app.SignalData.logFileFid, 'Driver: %s\n', app.SignalData.Driver);
                        fprintf(app.SignalData.logFileFid, 'Sampling frequency: %8.0f Hz\n', fs);
                    case 'independent'
                        fprintf(app.SignalData.logFileFid, 'Computer: %s\n', computer);
                        fprintf(app.SignalData.logFileFid, 'Input device: %s\n', devicesIn{InputdeviceID});
                        fprintf(app.SignalData.logFileFid, 'Output device: %s\n', devicesOut{OutputdeviceID});
                        fprintf(app.SignalData.logFileFid, 'Driver: %s\n', app.SignalData.Driver);
                        fprintf(app.SignalData.logFileFid, 'Sampling frequency: %8.0f Hz\n', fs);
                end
                %---
                nTmpBuffer = 8;
                app.SignalData.nTmpBuffer = nTmpBuffer;
                app.SignalData.running = "off";
                app.SignalData.signalBuffer = zeros(nTmpBuffer * bufferLength, 1);
                timeAxis = ((1:nTmpBuffer * bufferLength) - nTmpBuffer * bufferLength) / fs;
                fftl = length(timeAxis);
                app.SignalData.waveHandle = plot(app.WaveformMonitor,timeAxis, app.SignalData.signalBuffer);
                hold(app.WaveformMonitor,'on');
                app.SignalData.winEndHandle = plot(app.WaveformMonitor, ...
                    - app.WidthmsSlider.Value / 1000 * [1 1], [-1 1] ,'g', ...
                    'linewidth', 3);
                app.SignalData.fftl = fftl;
                app.SignalData.wLengthInMs = app.WidthmsSlider.Value;
                setWindowInformation(app);
                app.SignalData.winShapeHandle = plot(app.WaveformMonitor, ...
                    app.SignalData.wCursorTime * app.WidthmsSlider.Value ...
                    / 1000, app.SignalData.wCursor, "Color", [0 1 0], "LineWidth", 3);
                hold(app.WaveformMonitor,'off');
                axis(app.WaveformMonitor,[timeAxis(1) timeAxis(end) -1 1]);
                %--- for spectrum
                fx = (0:fftl - 1) / fftl * fs;
                app.SignalData.fAxis = fx;
                tmpY = rand(fftl, 1) - 0.5;
                w = app.SignalData.window;
                app.SignalData.lowLevelHandle = plot(app.SpectrumMonitor, ...
                    -90 * [1 1], [0 fs / 2], 'g', 'LineWidth', 3);
                hold(app.SpectrumMonitor, "on");
                app.SignalData.highLevelHandle = plot(app.SpectrumMonitor, ...
                    30 * [1 1], [0 fs / 2], 'g', 'LineWidth', 3);
                app.SignalData.specHandle = plot(app.SpectrumMonitor, ...
                    20 * log10(abs(fft(tmpY .* w))) , fx, 'k');
                hold(app.SpectrumMonitor, "off");
                set(app.SpectrumMonitor, 'ylim', [0 19999], 'YAxisLocation','right');
                set(app.SpectrumMonitor, 'xlim', [-40 80] - 50);
                ylabel(app.SpectrumMonitor, 'frequency (Hz)')
                xlabel(app.SpectrumMonitor, 'level (rel. dB)')
                %--- for whole wave viewer
                tt = (0:1/fs:3.5)' - 3.5;
                app.SignalData.wholeWave = (rand(length(tt), 1) - 0.5) * 0.8;
                app.SignalData.wholeWaveHandle = plot(app.WaveWholeAxis, tt, app.SignalData.wholeWave);
                set(app.WaveWholeAxis, 'xtick', [], 'xticklabel', [], 'xlim', ...
                    [-3 0] - app.WidthmsSlider.Value / 1000 / 2, 'ytickLabel', []);
                ylabel(app.WaveWholeAxis, 'amp.')
                %--- for spectrogram
                frameShift = bufferLength / fs;
                frameTimeBase = (0:frameShift / 4:3)';
                app.SignalData.frameTimeBase = frameTimeBase;
                nframes = length(frameTimeBase);
                app.SignalData.sgramVBins = 500;
                yData = (0:499) / 499 * 19999;
                app.SignalData.sgramView = rand(app.SignalData.sgramVBins, nframes) * 255;
                app.SignalData.sgramHandle = image(app.SgramAxis , ...
                    'xdata', frameTimeBase - 3  - app.WidthmsSlider.Value / 1000 / 2, ...
                    'ydata', yData, 'cdata', app.SignalData.sgramView);
                axis(app.SgramAxis, 'xy');
                set(app.SgramAxis, 'xlim', [-3 0] - app.WidthmsSlider.Value / 1000 / 2, ...
                    'ylim', [0 19999], 'ytickLabel', []);
                xlabel(app.SgramAxis, 'time (s)')
                ylabel(app.SgramAxis, 'linear frequency axis')
                set(app.SignalData.sgramHandle, 'HandleVisibility', 'off', ...
                    'Interruptible',  matlab.lang.OnOffSwitchState.off ,...
                    'HitTest', matlab.lang.OnOffSwitchState.off); 
                %---- for level bar graph
                app.SignalData.levelBarHandle = bar(app.LevelBarAxis, -20 + 90, ...
                    "FaceColor", [0 1 0]);
                hold(app.LevelBarAxis, "on")
                app.SignalData.peakLineHandle = plot(app.LevelBarAxis, ...
                    [0 2], -10 + [1 1] + 90, 'r', 'linewidth', 3);
                axis(app.LevelBarAxis,[0 2 0 90])
                ylabel(app.LevelBarAxis, 'Input Level (dB rel MSB)')
                set(app.LevelBarAxis, 'ytick', 0:10:90, 'ytickLabel', ...
                    num2str((-90:10:0)'));
                hold(app.LevelBarAxis, "on")
                grid(app.LevelBarAxis, "on")
                %---- prepare loop pink noise
                fftlPink = 2 ^ ceil(log2(fs * 1.4));
                x = pinknoise(fftlPink * 8);
                segment = x((1:fftlPink*2) + fftlPink * 2) .* sqrt(hanning(fftlPink * 2));
                y = x * 0;
                for ii = 0:fftlPink:length(x) - fftlPink * 2
                    y(ii + (1:2*fftlPink)) = y(ii + (1:2*fftlPink)) + segment;
                end
                app.SignalData.loopPink = y(2 * fftlPink + (1:2*fftlPink));
                disp(length(app.SignalData.loopPink));
                app.SignalData.calibrationLevel = 0;
            end
            close(f);
        end
        
        function results = updateLoop(app)
            results = [];
            bufferLength = app.SignalData.bufferSize;
            fs = app.SignalData.samplingFrequency;
            fftl = app.SignalData.fftl;
            app.SignalData.running = "on";
            nTmpBuffer = app.SignalData.nTmpBuffer;
            signalBuffer = zeros(nTmpBuffer * bufferLength, 1);
            postIdx = 1:(nTmpBuffer - 1) * bufferLength;
            preIdx = postIdx + bufferLength;
            captureIdx = (nTmpBuffer - 1) * bufferLength + (1:bufferLength);
            timeAxis = ((1:(nTmpBuffer - 1) * bufferLength) - (nTmpBuffer - 1) * bufferLength) / fs;
            app.SignalData.totalOverrun = 0;
            axis(app.WaveformMonitor,[timeAxis(1) timeAxis(end) -1 1]);
            set(app.SpectrumMonitor, 'ylim', [0 19999]);
            %app.SignalData.wCursor
            indexWholePre = 1:length(app.SignalData.wholeWave) - bufferLength;
            updateFaxis(app);
            set(app.SignalData.lowLevelHandle, 'xdata', [1 1] * app.LowLdBSlider.Value);
            set(app.SignalData.highLevelHandle, 'xdata', ...
                min(30, [1 1] * app.LowLdBSlider.Value + app.RangedBSlider.Value));
            app.RUNLamp.Visible = 'on';
            app.OVRLamp.Visible = 'off';
            app.SignalData.overRunCounter = 0;
            idxWin3 = circshift((1:fftl)', -3 * bufferLength/4);
            idxWin2 = circshift((1:fftl)', -2 * bufferLength/4);
            idxWin1 = circshift((1:fftl)', -bufferLength/4);
            tmpOut = zeros(bufferLength, 1);
            %---- speed test
            strtTic = tic;
            nTest = 40;
            for ii = 1:nTest
                signalBuffer(postIdx) = signalBuffer(preIdx);
                inputData = tmpOut;
                numOverrun = 1; 
                app.SignalData.totalOverrun = ...
                    app.SignalData.totalOverrun + numOverrun;
                app.SignalData.wholeWave(indexWholePre) = ...
                    app.SignalData.wholeWave(indexWholePre + bufferLength);
                app.SignalData.wholeWave(end - bufferLength + 1:end) = ...
                    inputData;
                signalBuffer = sgramUpdateBody(app, inputData, captureIdx, ...
                    signalBuffer, idxWin1, idxWin2, idxWin3, 0);
            end
            speedResult = toc(strtTic) / nTest;
            if speedResult > 0.015
                disp(['This machine is too slow. Realtime spectrogram disabled. ' ...
                    num2str(speedResult * 1000) ' ms'])
                sgramSkip = 1;
            else
                disp(['Update time:' num2str(speedResult * 1000) ' ms'])
                sgramSkip = 0;
            end
            %---- speed test end
            while app.SignalData.running == "on"
                signalBuffer(postIdx) = signalBuffer(preIdx);
                switch app.SignalData.audioMode
                    case 'simultaneous'
                        [inputData,numOverrun] = app.SignalData.recorder(tmpOut);
                    case 'independent'
                        [inputData,numOverrun] = app.SignalData.recorder();
                end
                app.SignalData.totalOverrun = ...
                    app.SignalData.totalOverrun + numOverrun;
                app.SignalData.wholeWave(indexWholePre) = ...
                    app.SignalData.wholeWave(indexWholePre + bufferLength);
                app.SignalData.wholeWave(end - bufferLength + 1:end) = ...
                    inputData;
                signalBuffer = sgramUpdateBody(app, inputData, captureIdx, ...
                    signalBuffer, idxWin1, idxWin2, idxWin3, sgramSkip);
                drawnow limitrate
            end
            set(app.SignalData.wholeWaveHandle, 'ydata', app.SignalData.wholeWave);
            tmpYmax = max(abs(app.SignalData.wholeWave));
            if tmpYmax <= 0 || tmpYmax > 1
                tmpYmax = 1;
            end
            set(app.WaveWholeAxis, 'ylim', tmpYmax * [-1 1]);
            redrawSgram(app);
            release(app.SignalData.recorder);
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
        end
        
        function results = setWindowInformation(app)
                        results = [];
            fftl = app.SignalData.fftl;
            fs = app.SignalData.samplingFrequency;
            wLengthInMs = app.SignalData.wLengthInMs;
            wLength = min(fftl, round(wLengthInMs / 1000 * fs));
            switch app.TypeDropDown.Value
                case app.TypeDropDown.Items{1}
                    wTmp = ones(wLength, 1);
                    wCursor = ones(200, 1);
                case app.TypeDropDown.Items{2}
                    wTmp = bartlett(wLength);
                    wCursor = bartlett(200);
                case app.TypeDropDown.Items{3}
                    wTmp = hamming(wLength);
                    wCursor = hamming(200);
                case app.TypeDropDown.Items{4}
                    wTmp = hann(wLength);
                    wCursor = hann(200);
                case app.TypeDropDown.Items{5}
                    wTmp = blackman(wLength);
                    wCursor = blackman(200);
                case app.TypeDropDown.Items{6}
                    wTmp = nuttallwin(wLength);
                    wCursor = nuttallwin(200);
            end
            wCursorTime = ((1:200) - 200) / 200;
            app.SignalData.wCursor = [wCursor -wCursor];
            app.SignalData.wCursorTime = wCursorTime;
            wTmp = wTmp / sqrt(sum(wTmp));
            w = zeros(fftl, 1);
            w(end - wLength + 1:end) = wTmp;
            app.SignalData.window = w;
            set(app.WaveWholeAxis, 'xlim', [-3 0] - app.SignalData.wLengthInMs / 1000 / 2 );
        end
        
        function results = updateFaxis(app)
            results = [];
            panCenter = app.PANkHzSlider.Value * 1000;
            fs = app.SignalData.samplingFrequency;
            xView = [0 20000] / app.MAGSlider.Value;
            xCenter = sum(xView) / 2;
            xLow = xView(1) - xCenter + panCenter;
            xHigh = xView(2) - xCenter + panCenter;
            if xLow < 0
                yLim = xView;
            elseif xHigh > fs / 2
                yLim = xView - xView(2) + 20000;
            else
                yLim = xView - xCenter + panCenter;
            end
            set(app.SpectrumMonitor, 'ylim', min(19999,yLim));

        end
        
        function results = redrawSgram(app)
            results = [];
            fftl = app.SignalData.fftl;
            ylimSpec = get(app.SpectrumMonitor, 'ylim');
            viewAxis = ylimSpec(1) + (0:499)' / 499 * diff(ylimSpec);
            lowLevel = get(app.SignalData.lowLevelHandle, 'xdata');
            highLevel = get(app.SignalData.highLevelHandle, 'xdata');
            bufferLength = app.SignalData.bufferSize;
            nFrameShift = bufferLength / 4;
            setWindowInformation(app);
            w = app.SignalData.window;
            x = app.SignalData.wholeWave;
            sgramView = app.SignalData.sgramView;
            bufferTop = length(x) - fftl;
            baseIdx = (1:fftl)';
            rawPowerSgram = zeros(fftl, size(sgramView, 2));
            idFrame = size(sgramView, 2);
            for ii = bufferTop:-nFrameShift:1
                tmp = x(ii + baseIdx);
                rawPowerSgram(:, idFrame) = abs(fft(tmp .* w));
                idFrame = max(1,idFrame - 1);
            end
            for ii = 1:idFrame
                rawPowerSgram(:, ii) = rawPowerSgram(:, idFrame + 1);
            end
            rawPowerSgram = abs(rawPowerSgram) .^ 2;
            cumPower = cumsum(rawPowerSgram) * app.SignalData.fAxis(2);
            cumSlice = interp1(app.SignalData.fAxis, cumPower, ...
                viewAxis - app.SignalData.fAxis(2) / 2, "linear", "extrap");
            sgramSlice = (cumSlice([2:end end], :) - cumSlice) / (viewAxis(2) - viewAxis(1));
            sgramSlice(end, :) = sgramSlice(end - 1, :);
            sgramSlice = 10 * log10(sgramSlice);
            app.SignalData.sgramView = max(0, min(255, ...
                (sgramSlice - lowLevel(1)) / (highLevel(1) - lowLevel(1)) * 255));
            set(app.SgramAxis, 'xlim', [-3 0] - app.SignalData.wLengthInMs / 1000 / 2 );
            set(app.SignalData.sgramHandle, 'cdata', app.SignalData.sgramView);
            set(app.SignalData.sgramHandle, 'xdata', ...
                app.SignalData.frameTimeBase - 3 - app.SignalData.wLengthInMs / 1000 / 2 );
            drawnow limitrate;
        end
        
        function signalBuffer = sgramUpdateBody(app, inputData, captureIdx, ...
                    signalBuffer, idxWin1, idxWin2, idxWin3, sgramSkip)
            signalBuffer(captureIdx) = inputData;
            %--- wave monitor update
            set(app.SignalData.waveHandle, 'ydata', signalBuffer);
            tmpYmax = max(abs(signalBuffer));
            if tmpYmax <= 0 || tmpYmax > 1
                tmpYmax = 1;
            end
            set(app.WaveformMonitor, 'ylim', tmpYmax * [-1 1]);
            set(app.SignalData.winShapeHandle(1), 'ydata', ...
                tmpYmax * app.SignalData.wCursor(: ,1));
            set(app.SignalData.winShapeHandle(2), 'ydata', ...
                tmpYmax * app.SignalData.wCursor(: ,2));
            set(app.SignalData.levelBarHandle, 'ydata', ...
                20 * log10(std(signalBuffer)) + 90);
            set(app.SignalData.peakLineHandle, 'ydata', ...
                20 * log10(max(abs(signalBuffer))) * [1, 1] + 90);
            %--- whole wave view update
            if sgramSkip == 0
            set(app.SignalData.wholeWaveHandle, 'ydata', app.SignalData.wholeWave);
            tmpYmax = max(abs(app.SignalData.wholeWave));
            if tmpYmax <= 0 || tmpYmax > 1
                tmpYmax = 1;
            end
            set(app.WaveWholeAxis, 'ylim', tmpYmax * [-1 1]);
            %--- spectrum monitor update
            powerSlice = abs(fft(signalBuffer .* app.SignalData.window)) .^ 2;
            dBspec = 10*log10(powerSlice);
            set(app.SignalData.specHandle, 'xdata', dBspec);
            set(app.SpectrumMonitor, 'xlim', [-40 80] - 50);
            %--- spectrogram view update
            ylimSpec = get(app.SpectrumMonitor, 'ylim');
            viewAxis = ylimSpec(1) + (0:499)' / 499 * diff(ylimSpec);
            lowLevel = get(app.SignalData.lowLevelHandle, 'xdata');
            highLevel = get(app.SignalData.highLevelHandle, 'xdata');
            wtmp = app.SignalData.window;
            powerBlock = abs(fft([signalBuffer signalBuffer  signalBuffer  signalBuffer ] ...
                .* [wtmp(idxWin3) wtmp(idxWin2) wtmp(idxWin1) wtmp])) .^ 2;
            cumPower = cumsum(powerBlock) * app.SignalData.fAxis(2);
            cumSlice = interp1(app.SignalData.fAxis, cumPower, ...
                viewAxis - app.SignalData.fAxis(2) / 2, "linear", "extrap");
            sgramSlice = (cumSlice([2:end end], :) - cumSlice) / (viewAxis(2) - viewAxis(1));
            sgramSlice(end, :) = sgramSlice(end - 1, :);
            sgramSlice = 10 * log10(sgramSlice);
            app.SignalData.sgramView(:, 1:end-4) = app.SignalData.sgramView(:, 5:end);
            app.SignalData.sgramView(:, end-3:end) = max(0, min(255, ...
                (sgramSlice - lowLevel(1)) / (highLevel(1) - lowLevel(1)) * 255));
            set(app.SgramAxis, 'xlim', [-3 0] - app.SignalData.wLengthInMs / 1000 / 2 );
            set(app.SignalData.sgramHandle, 'cdata', app.SignalData.sgramView);
            set(app.SignalData.sgramHandle, 'xdata', ...
                app.SignalData.frameTimeBase - 3 - app.SignalData.wLengthInMs / 1000 / 2 );
            end
            if app.SignalData.totalOverrun > 0 && app.SignalData.overRunCounter == 0
                app.OVRLamp.Visible = 'on';
                app.SignalData.overRunCounter = 20;
            elseif app.SignalData.overRunCounter > 0
                app.SignalData.overRunCounter = app.SignalData.overRunCounter - 1;
                if app.SignalData.overRunCounter == 0
                    app.SignalData.totalOverrun = 0;
                end
            else
                app.OVRLamp.Visible = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeAudioInput(app);
            app.STOPButton.Enable = 'off';
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            disp("BYE...")
            if isfield(app.SignalData, 'logFileFid')
                fclose(app.SignalData.logFileFid);
            end
            pause(0.5);
            close(app.UIFigure);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.SignalData.running = "off";
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            disableDefaultInteractivity(app.WaveformMonitor);
            disableDefaultInteractivity(app.SpectrumMonitor);
            disableDefaultInteractivity(app.WaveWholeAxis);
            disableDefaultInteractivity(app.SgramAxis);
            fprintf(app.SignalData.logFileFid, 'A/D started: %s\n', datestr(now));
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.PLAYButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.TestsignalstartButton.Enable = 'off';
            updateLoop(app);
            enableDefaultInteractivity(app.WaveWholeAxis);
            enableDefaultInteractivity(app.SgramAxis);
            enableDefaultInteractivity(app.WaveformMonitor);
            enableDefaultInteractivity(app.SpectrumMonitor);
            fprintf(app.SignalData.logFileFid, 'A/D stopped: %s\n', datestr(now));
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.PLAYButton.Enable = 'on';
            app.SAVEButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Value changing function: WidthmsSlider
        function WidthmsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SignalData.wLengthInMs = changingValue;
            setWindowInformation(app);
            set(app.SignalData.winEndHandle, 'xdata', ...
                - changingValue / 1000 * [1 1]);
            set(app.SignalData.winShapeHandle, 'xdata', ...
                changingValue / 1000 * app.SignalData.wCursorTime);
            app.SignalData.totalOverrun = 0;
            if app.SignalData.running == "off"
                redrawSgram(app);
            end
        end

        % Value changing function: MAGSlider
        function MAGSliderValueChanging(app, event)
            changingValue = event.Value;
            panCenter = app.PANkHzSlider.Value * 1000;
            %fs = app.SignalData.samplingFrequency;
            xView = [0 20000] / changingValue;
            xCenter = sum(xView) / 2;
            xLow = xView(1) - xCenter + panCenter;
            xHigh = xView(2) - xCenter + panCenter;
            if xLow < 0
                yLim = xView;
            elseif xHigh > 20000
                yLim = xView - xView(2) + 20000;
            else
                yLim = xView - xCenter + panCenter;
            end
            set(app.SpectrumMonitor, 'ylim', min(19999,yLim));
            if app.SignalData.running == "off"
                redrawSgram(app);
            end
        end

        % Value changing function: PANkHzSlider
        function PANkHzSliderValueChanging(app, event)
            changingValue = event.Value;
            panCenter = changingValue * 1000;
            fs = app.SignalData.samplingFrequency;
            xView = [0 20000] / app.MAGSlider.Value;
            xCenter = sum(xView) / 2;
            xLow = xView(1) - xCenter + panCenter;
            xHigh = xView(2) - xCenter + panCenter;
            if xLow < 0
                yLim = xView;
            elseif xHigh > fs / 2
                yLim = xView - xView(2) + 20000;
            else
                yLim = xView - xCenter + panCenter;
            end
            set(app.SpectrumMonitor, 'ylim', min(19999,yLim));
            if app.SignalData.running == "off"
                redrawSgram(app);
            end
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            app.SignalData.wLengthInMs = app.WidthmsSlider.Value;
            setWindowInformation(app);
            yMax = max(get(app.WaveformMonitor, 'ylim'));
            set(app.SignalData.winEndHandle, 'xdata', ...
                - app.WidthmsSlider.Value / 1000 * [1 1]);
            set(app.SignalData.winShapeHandle, 'xdata', ...
                app.WidthmsSlider.Value / 1000 * app.SignalData.wCursorTime);
            set(app.SignalData.winShapeHandle(1), 'ydata', ...
                yMax * app.SignalData.wCursor(: ,1));
            set(app.SignalData.winShapeHandle(2), 'ydata', ...
                yMax * app.SignalData.wCursor(: ,2));
            app.SignalData.totalOverrun = 0;
            if app.SignalData.running == "off"
                redrawSgram(app);
            end
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            message = {'Copyright 2021 Hideki Kawahara'; ' '; ...
                'Licensed under the Apache License, Version 2.0 (the "License");'; ...
                'you may not use this file except in compliance with the License.'; ...
                'You may obtain a copy of the License at'; '  '; ...
                '    http://www.apache.org/licenses/LICENSE-2.0'; '  '; ...
                'Unless required by applicable law or agreed to in writing, software'; ...
                'distributed under the License is distributed on an "AS IS" BASIS,'; ...
                'WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.'; ...
                'See the License for the specific language governing permissions and'; ...
                'limitations under the License.'};
            msgbox(message)
        end

        % Value changing function: LowLdBSlider
        function LowLdBSliderValueChanging(app, event)
            changingValue = event.Value;
            set(app.SignalData.lowLevelHandle, 'xdata', [1 1] * changingValue);
            set(app.SignalData.highLevelHandle, 'xdata', ...
                min(30, [1 1] * changingValue + app.RangedBSlider.Value));
            if app.SignalData.running == "off"
                redrawSgram(app);
            end
        end

        % Value changing function: RangedBSlider
        function RangedBSliderValueChanging(app, event)
            changingValue = event.Value;
            set(app.SignalData.highLevelHandle, 'xdata', ...
                min(30, [1 1] * changingValue + app.LowLdBSlider.Value));   
            if app.SignalData.running == "off"
                redrawSgram(app);
            end
        end

        % Value changed function: CMapDropDown
        function CMapDropDownValueChanged(app, event)
            value = app.CMapDropDown.Value;
            switch value
                case 'parula'
                    colormap(app.SgramAxis, "parula");
                case 'turbo'
                    colormap(app.SgramAxis, "turbo");
                case 'gray'
                    colormap(app.SgramAxis, "gray");
                case '1-gray'
                    tmp = colormap(app.SgramAxis,"gray");
                    colormap(app.SgramAxis, 1-tmp);
                case 'hot'
                    colormap(app.SgramAxis, "hot");
            end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            fileNameRoot = "rtSgAcq" + datestr(now, 30);
            exportapp(app.UIFigure, ...
                app.SignalData.workingDirectory + "/" + fileNameRoot + ".pdf");
            audiowrite(app.SignalData.workingDirectory + "/" + fileNameRoot + ".wav", ...
                app.SignalData.wholeWave, ...
                app.SignalData.samplingFrequency, "BitsPerSample", 24);
            fprintf(app.SignalData.logFileFid, 'Data saved: %s\n', datestr(now));
            app.SAVEButton.Enable = 'off';
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            bufferLength = app.SignalData.bufferSize;
            fs = app.SignalData.samplingFrequency;
            %app.SignalData.player = audioDeviceWriter(fs, "BufferSize", ...
            %    bufferLength,"Device", app.SignalData.Device);
            baseIdx = 1:bufferLength;
            initialPoint = 0;
            app.SignalData.running = "on";
            fprintf(app.SignalData.logFileFid, 'Played back: %s\n', datestr(now));
            cumTime = 0;
            totalUnderRun = 0;
            app.PLAYButton.Enable = 'off';
            app.STARTButton.Enable = 'off';
            app.SAVEButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.RUNLamp.Visible = 'on';
            app.TestsignalstartButton.Enable = 'off';
            drawnow
            pause(0.1)
            %varInput = 0;
            while initialPoint < length(app.SignalData.wholeWave) - bufferLength
                tmpOut = app.SignalData.wholeWave(initialPoint + baseIdx);
                switch app.SignalData.audioMode
                    case 'simultaneous'
                        [~, numUnderRun] = app.SignalData.recorder(tmpOut);
                    case 'independent'
                        numUnderRun = app.SignalData.player(tmpOut); 
                        pause(0.80 * bufferLength / fs);
                        cumTime = cumTime + 0.80 * bufferLength / fs;
                end
                initialPoint = initialPoint + bufferLength;
                totalUnderRun = totalUnderRun + numUnderRun;
                %varInput = varInput + std(audioIn) .^ 2;
            end
            switch app.SignalData.audioMode
                case 'independent'
                    pause(max(0.1, length(app.SignalData.wholeWave) / fs - cumTime));
                    release(app.SignalData.player);
                case 'simultaneous'
                    pause(0.1)
                    release(app.SignalData.recorder);
            end
            app.SignalData.running = "off";
            app.PLAYButton.Enable = 'on';
            app.STARTButton.Enable = 'on';
            app.SAVEButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.RUNLamp.Visible = 'off';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: TestsignalstartButton
        function TestsignalstartButtonPushed(app, event)
            disableDefaultInteractivity(app.WaveformMonitor);
            disableDefaultInteractivity(app.SpectrumMonitor);
            disableDefaultInteractivity(app.WaveWholeAxis);
            disableDefaultInteractivity(app.SgramAxis);
            disableDefaultInteractivity(app.LevelBarAxis);
            bufferLength = app.SignalData.bufferSize;
            %fs = app.SignalData.samplingFrequency;
            baseIdx = 1:bufferLength;
            initialPoint = 0;
            app.SignalData.running = "on";
            fprintf(app.SignalData.logFileFid, 'Test Signal started: %s\n', datestr(now));
            totalUnderRun = 0;
            app.PLAYButton.Enable = 'off';
            app.STARTButton.Enable = 'off';
            app.STOPButton.Enable = 'on';
            app.SAVEButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            app.RUNLamp.Visible = 'on';
            app.dBButton70.Enable = 'on';
            app.dBButton80.Enable = 'on';
            app.TestsignalstartButton.Enable = 'off';
            drawnow
            pause(0.1)
            varInput = 0;
            sigLength = length(app.SignalData.loopPink);
            app.SignalData.loopBuffer = zeros(sigLength, 1);
            preIdx = 1:sigLength - bufferLength;
            postIdx = (1:sigLength - bufferLength) + bufferLength;
            pickerIdx = round((1:sigLength / 2) + sigLength / 2 - bufferLength * 2);
            while app.SignalData.running == "on"
                app.SignalData.loopBuffer(preIdx) = app.SignalData.loopBuffer(postIdx);
                tmpOut = app.SignalData.loopPink(initialPoint + baseIdx);
                switch app.SignalData.audioMode
                    case 'simultaneous'
                        [audioIn, numUnderRun] = app.SignalData.recorder(tmpOut);
                    case 'independent'
                        [audioIn, numUnderRun1] = app.SignalData.recorder();
                        numUnderRun = app.SignalData.player(tmpOut) + numUnderRun1;
                end
                app.SignalData.loopBuffer(sigLength - bufferLength + baseIdx) = audioIn;
                initialPoint = initialPoint + bufferLength;
                totalUnderRun = totalUnderRun + numUnderRun;
                varInput = varInput + std(audioIn) .^ 2;
                initialPoint = rem(initialPoint, sigLength);
                set(app.SignalData.levelBarHandle, 'ydata', ...
                    20 * log10(std(app.SignalData.loopBuffer(pickerIdx))) + 90);
                set(app.SignalData.peakLineHandle, 'ydata', ...
                    20 * log10(max(abs(app.SignalData.loopBuffer(pickerIdx)))) * [1, 1] + 90);
                drawnow limitrate
            end
            pause(0.1);
            release(app.SignalData.recorder);
            app.SignalData.running = "off";
            app.PLAYButton.Enable = 'on';
            app.STARTButton.Enable = 'on';
            app.SAVEButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
            app.RUNLamp.Visible = 'off';
            enableDefaultInteractivity(app.WaveformMonitor);
            enableDefaultInteractivity(app.SpectrumMonitor);
            enableDefaultInteractivity(app.WaveWholeAxis);
            enableDefaultInteractivity(app.SgramAxis);
            enableDefaultInteractivity(app.LevelBarAxis);
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: dBButton80
        function dBButton80Pushed(app, event)
            app.SignalData.running = "off";
            app.dBButton70.Enable = 'off';
            app.dBButton80.Enable = 'off';
            fs = app.SignalData.samplingFrequency;
            bufferLength = app.SignalData.bufferSize;
            loopBuffer = app.SignalData.loopBuffer;
            sigLength = length(app.SignalData.loopPink);
            wFilter = weightingFilter("A-weighting","SampleRate", fs);
            y = wFilter(loopBuffer);
            pickerIdx = round((1:sigLength / 2) + sigLength / 2 - bufferLength * 2);
            signalDB = 20 * log10(std(y(pickerIdx)));
            app.SignalData.calibrationLevel = 80 - signalDB;
            app.Lamp80rab.Visible = 'on';
            app.CalLevelLabel.Text = [num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                ' dB'];
            drawnow
            fprintf(app.SignalData.logFileFid, 'Calibration 80dB: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Calibration data: %8.2f\n', app.SignalData.calibrationLevel);
            app.RESETButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: dBButton70
        function dBButton70Pushed(app, event)
            app.SignalData.running = "off";
            app.dBButton70.Enable = 'off';
            app.dBButton80.Enable = 'off';
            fs = app.SignalData.samplingFrequency;
            bufferLength = app.SignalData.bufferSize;
            loopBuffer = app.SignalData.loopBuffer;
            sigLength = length(app.SignalData.loopPink);
            wFilter = weightingFilter("A-weighting","SampleRate", fs);
            y = wFilter(loopBuffer);
            pickerIdx = round((1:sigLength / 2) + sigLength / 2 - bufferLength * 2);
            signalDB = 20 * log10(std(y(pickerIdx)));
            app.SignalData.calibrationLevel = 70 - signalDB;
            app.Lamp70dB.Visible = 'on';
            fprintf(app.SignalData.logFileFid, 'Calibration 70dB: %s\n', datestr(now));
            fprintf(app.SignalData.logFileFid, 'Calibration data: %8.2f\n', app.SignalData.calibrationLevel);
            app.CalLevelLabel.Text = [num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                ' dB'];
            drawnow
            app.RESETButton.Enable = 'on';
            app.TestsignalstartButton.Enable = 'on';
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.TestsignalstartButton.Enable = 'on';
            app.RESETButton.Enable = 'off';
            app.SignalData.calibrationLevel = 0;
            app.Lamp80rab.Visible = 'off';
            app.Lamp70dB.Visible = 'off';
            app.CalLevelLabel.Text = [num2str(app.SignalData.calibrationLevel, '%8.2f') ...
                ' dB'];
            drawnow
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {652, 652};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {208, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Enable = 'off';
            app.STOPButton.Text = 'STOP';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.RUNLampLabel = uilabel(app.LeftPanel);
            app.RUNLampLabel.Text = 'RUN';
            app.RUNLamp = uilamp(app.LeftPanel);
            app.RUNLamp.Visible = 'off';
            app.OVRLampLabel = uilabel(app.LeftPanel);
            app.OVRLampLabel.Text = 'OVR';
            app.OVRLamp = uilamp(app.LeftPanel);
            app.OVRLamp.Visible = 'off';
            app.RealtimespectrogramwithAcquisitionSP3ARKLabel = uilabel(app.LeftPanel);
            app.RealtimespectrogramwithAcquisitionSP3ARKLabel.Text = {'Real-time'; 'spectrogram with'; 'Acquisition'; '(SP3ARK)'};
            app.TypeDropDownLabel = uilabel(app.LeftPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.LeftPanel);
            app.TypeDropDown.Items = {'rectangular', 'Bartlet', 'Hamming', 'Hanning', 'Blackman', 'Nuttall'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Blackman';
            app.AboutButton = uibutton(app.LeftPanel, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.CMapDropDownLabel = uilabel(app.LeftPanel);
            app.CMapDropDownLabel.Text = 'CMap';
            app.CMapDropDown = uidropdown(app.LeftPanel);
            app.CMapDropDown.Items = {'parula', 'turbo', 'gray', '1-gray', 'hot'};
            app.CMapDropDown.ValueChangedFcn = createCallbackFcn(app, @CMapDropDownValueChanged, true);
            app.CMapDropDown.Value = 'parula';
            app.CALIBRATIONPanel = uipanel(app.LeftPanel);
            app.CALIBRATIONPanel.Title = 'CALIBRATION';
            app.TestsignalstartButton = uibutton(app.CALIBRATIONPanel, 'push');
            app.TestsignalstartButton.ButtonPushedFcn = createCallbackFcn(app, @TestsignalstartButtonPushed, true);
            app.TestsignalstartButton.Text = 'Test signal start';
            app.dBButton70 = uibutton(app.CALIBRATIONPanel, 'push');
            app.dBButton70.ButtonPushedFcn = createCallbackFcn(app, @dBButton70Pushed, true);
            app.dBButton70.Enable = 'off';
            app.dBButton70.Text = '70dB';
            app.dBButton80 = uibutton(app.CALIBRATIONPanel, 'push');
            app.dBButton80.ButtonPushedFcn = createCallbackFcn(app, @dBButton80Pushed, true);
            app.dBButton80.Enable = 'off';
            app.dBButton80.Text = '80dB';
            app.Lamp70dB = uilamp(app.CALIBRATIONPanel);
            app.Lamp70dB.Visible = 'off';
            app.Lamp80rab = uilamp(app.CALIBRATIONPanel);
            app.Lamp80rab.Visible = 'off';
            app.RESETButton = uibutton(app.CALIBRATIONPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Enable = 'off';
            app.RESETButton.Text = 'RESET';
            app.CalLevelLabel = uilabel(app.CALIBRATIONPanel);
            app.CalLevelLabel.Text = '0';
            app.SAVEButton = uibutton(app.LeftPanel, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Enable = 'off';
            app.SAVEButton.Text = 'SAVE';
            app.PLAYButton = uibutton(app.LeftPanel, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Enable = 'off';
            app.PLAYButton.Text = 'PLAY';
            app.RightPanel = uipanel(app.GridLayout);
            app.WidthmsSliderLabel = uilabel(app.RightPanel);
            app.WidthmsSliderLabel.Text = {'Width'; 'ms'};
            app.WidthmsSlider = uislider(app.RightPanel);
            app.WidthmsSlider.Limits = [10 120];
            app.WidthmsSlider.ValueChangingFcn = createCallbackFcn(app, @WidthmsSliderValueChanging, true);
            app.WidthmsSlider.Value = 120;
            app.PANkHzSliderLabel = uilabel(app.RightPanel);
            app.PANkHzSliderLabel.Text = {'PAN'; 'kHz'};
            app.PANkHzSlider = uislider(app.RightPanel);
            app.PANkHzSlider.Limits = [0 20];
            app.PANkHzSlider.ValueChangingFcn = createCallbackFcn(app, @PANkHzSliderValueChanging, true);
            app.MAGSliderLabel = uilabel(app.RightPanel);
            app.MAGSliderLabel.Text = 'MAG';
            app.MAGSlider = uislider(app.RightPanel);
            app.MAGSlider.Limits = [1 20];
            app.MAGSlider.ValueChangingFcn = createCallbackFcn(app, @MAGSliderValueChanging, true);
            app.MAGSlider.Value = 1;
            app.RangedBSliderLabel = uilabel(app.RightPanel);
            app.RangedBSliderLabel.Text = {'Range'; 'dB'};
            app.RangedBSlider = uislider(app.RightPanel);
            app.RangedBSlider.Limits = [20 120];
            app.RangedBSlider.ValueChangingFcn = createCallbackFcn(app, @RangedBSliderValueChanging, true);
            app.RangedBSlider.Value = 120;
            app.LowLdBSliderLabel = uilabel(app.RightPanel);
            app.LowLdBSliderLabel.Text = {'LowL'; 'dB'};
            app.LowLdBSlider = uislider(app.RightPanel);
            app.LowLdBSlider.Limits = [-90 30];
            app.LowLdBSlider.ValueChangingFcn = createCallbackFcn(app, @LowLdBSliderValueChanging, true);
            app.LowLdBSlider.Value = -90;
            app.WaveformMonitor = uiaxes(app.RightPanel);
            title(app.WaveformMonitor, 'waveform monitor')
            xlabel(app.WaveformMonitor, 'time (s)')
            ylabel(app.WaveformMonitor, 'amplitude')
            zlabel(app.WaveformMonitor, 'Z')
            app.WaveformMonitor.YTick = [-1 0 1];
            app.WaveformMonitor.YTickLabel = {'-1'; '0'; '1'};
            app.SpectrumMonitor = uiaxes(app.RightPanel);
            title(app.SpectrumMonitor, ' ')
            xlabel(app.SpectrumMonitor, 'X')
            ylabel(app.SpectrumMonitor, 'Y')
            zlabel(app.SpectrumMonitor, 'Z')
            app.SgramAxis = uiaxes(app.RightPanel);
            title(app.SgramAxis, '  ')
            xlabel(app.SgramAxis, 'X')
            ylabel(app.SgramAxis, 'Y')
            zlabel(app.SgramAxis, 'Z')
            app.SgramAxis.XLim = [0 0.999];
            app.SgramAxis.YTick = [-90 0];
            app.SgramAxis.YTickLabel = {'-90'; '0'};
            app.WaveWholeAxis = uiaxes(app.RightPanel);
            ylabel(app.WaveWholeAxis, 'Y')
            zlabel(app.WaveWholeAxis, 'Z')
            app.WaveWholeAxis.YLim = [-0.99 0.99];
            app.WaveWholeAxis.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.WaveWholeAxis.XTickLabel = '';
            app.WaveWholeAxis.YTick = [-1 0 1];
            app.WaveWholeAxis.YTickLabel = {'-1.0'; '0.0'; '1.0'};
            app.LevelBarAxis = uiaxes(app.RightPanel);
            title(app.LevelBarAxis, 'Level')
            ylabel(app.LevelBarAxis, 'Y')
            zlabel(app.LevelBarAxis, 'Z')
            app.LevelBarAxis.XTick = [];
            app.LevelBarAxis.XTickLabel = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rtSgramWithAcqusition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end