classdef sgramViewerTw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        STARTButton                   matlab.ui.control.Button
        STOPButton                    matlab.ui.control.Button
        QUITButton                    matlab.ui.control.Button
        RUNLampLabel                  matlab.ui.control.Label
        RUNLamp                       matlab.ui.control.Lamp
        OVRLampLabel                  matlab.ui.control.Label
        OVRLamp                       matlab.ui.control.Lamp
        SpectrogramviewerSP3ARKLabel  matlab.ui.control.Label
        TypeDropDownLabel             matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
        AboutButton                   matlab.ui.control.Button
        CMapDropDownLabel             matlab.ui.control.Label
        CMapDropDown                  matlab.ui.control.DropDown
        RightPanel                    matlab.ui.container.Panel
        WidthmsSliderLabel            matlab.ui.control.Label
        WidthmsSlider                 matlab.ui.control.Slider
        PANkHzSliderLabel             matlab.ui.control.Label
        PANkHzSlider                  matlab.ui.control.Slider
        MAGSliderLabel                matlab.ui.control.Label
        MAGSlider                     matlab.ui.control.Slider
        RangedBSliderLabel            matlab.ui.control.Label
        RangedBSlider                 matlab.ui.control.Slider
        LowLdBSliderLabel             matlab.ui.control.Label
        LowLdBSlider                  matlab.ui.control.Slider
        WaveformMonitor               matlab.ui.control.UIAxes
        SpectrumMonitor               matlab.ui.control.UIAxes
        SgramAxis                     matlab.ui.control.UIAxes
        WaveWholeAxis                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SignalData % Description
    end
    
    methods (Access = private)
        
        function results = initializeAudioInput(app)
            results = [];
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
            fs = 44100;
            bufferLength = 1024;
            recorderObj = audioDeviceReader;
            devices = getAudioDevices(recorderObj);
            [deviceID,tf] = listdlg('ListString',devices, 'SelectionMode','single');
            if ~tf
                close(app.UIFigure);
            else
                app.SignalData.Device = devices{deviceID};
                app.SignalData.recorder = audioDeviceReader(fs, bufferLength, "ChannelMapping", 1, "BitDepth","24-bit integer");
                app.SignalData.samplingFrequency = fs;
                app.SignalData.bufferSize = bufferLength;
                app.SignalData.running = "off";
                app.SignalData.signalBuffer = zeros(16 * bufferLength, 1);
                timeAxis = ((1:16 * bufferLength) - 16 * bufferLength) / fs;
                fftl = length(timeAxis);
                app.SignalData.waveHandle = plot(app.WaveformMonitor,timeAxis, app.SignalData.signalBuffer);
                hold(app.WaveformMonitor,'on');
                app.SignalData.winEndHandle = plot(app.WaveformMonitor, ...
                    - app.WidthmsSlider.Value / 1000 * [1 1], [-1 1] ,'g', ...
                    'linewidth', 3);
                app.SignalData.fftl = fftl;
                app.SignalData.wLengthInMs = app.WidthmsSlider.Value;
                setWindowInformation(app);
                app.SignalData.winShapeHandle = plot(app.WaveformMonitor, ...
                    app.SignalData.wCursorTime * app.WidthmsSlider.Value ...
                    / 1000, app.SignalData.wCursor, "Color", [0 1 0], "LineWidth", 3);
                hold(app.WaveformMonitor,'off');
                axis(app.WaveformMonitor,[timeAxis(1) timeAxis(end) -1 1]);
                %--- for spectrum
                fx = (0:fftl - 1) / fftl * fs;
                app.SignalData.fAxis = fx;
                tmpY = rand(fftl, 1) - 0.5;
                w = app.SignalData.window;
                app.SignalData.lowLevelHandle = plot(app.SpectrumMonitor, ...
                    -90 * [1 1], [0 fs / 2], 'g', 'LineWidth', 3);
                hold(app.SpectrumMonitor, "on")
                app.SignalData.highLevelHandle = plot(app.SpectrumMonitor, ...
                    30 * [1 1], [0 fs / 2], 'g', 'LineWidth', 3);
                app.SignalData.specHandle = plot(app.SpectrumMonitor, ...
                    20 * log10(abs(fft(tmpY .* w))) , fx, 'k');
                hold(app.SpectrumMonitor, "off")
                set(app.SpectrumMonitor, 'ylim', [0 19999], 'YAxisLocation','right');
                set(app.SpectrumMonitor, 'xlim', [-40 80] - 50);
                ylabel(app.SpectrumMonitor, 'frequency (Hz)')
                xlabel(app.SpectrumMonitor, 'level (rel. dB)')
                %--- for whole wave viewer
                tt = (0:1/fs:3.5)' - 3.5;
                app.SignalData.wholeWave = (rand(length(tt), 1) - 0.5) * 0.8;
                app.SignalData.wholeWaveHandle = plot(app.WaveWholeAxis, tt, app.SignalData.wholeWave);
                set(app.WaveWholeAxis, 'xtick', [], 'xticklabel', [], 'xlim', ...
                    [-3 0] - app.WidthmsSlider.Value / 1000 / 2, 'ytickLabel', []);
                ylabel(app.WaveWholeAxis, 'amp.')
                %--- for spectrogram
                frameShift = bufferLength / fs;
                frameTimeBase = (0:frameShift / 4:3)';
                app.SignalData.frameTimeBase = frameTimeBase;
                nframes = length(frameTimeBase);
                app.SignalData.sgramVBins = 500;
                yData = (0:499) / 499 * 19999;
                app.SignalData.sgramView = rand(app.SignalData.sgramVBins, nframes) * 255;
                app.SignalData.sgramHandle = image(app.SgramAxis , ...
                    'xdata', frameTimeBase - 3  - app.WidthmsSlider.Value / 1000 / 2, ...
                    'ydata', yData, 'cdata', app.SignalData.sgramView);
                axis(app.SgramAxis, 'xy');
                set(app.SgramAxis, 'xlim', [-3 0] - app.WidthmsSlider.Value / 1000 / 2, ...
                    'ylim', [0 19999], 'ytickLabel', []);
                xlabel(app.SgramAxis, 'time (s)')
                ylabel(app.SgramAxis, 'linear frequency axis')
                set(app.SignalData.sgramHandle, 'HandleVisibility', 'off', ...
                    'Interruptible',  matlab.lang.OnOffSwitchState.off ,...
                    'HitTest', matlab.lang.OnOffSwitchState.off); 
            end
        end
        
        function results = updateLoop(app)
            results = [];
            bufferLength = app.SignalData.bufferSize;
            fs = app.SignalData.samplingFrequency;
            fftl = app.SignalData.fftl;
            app.SignalData.running = "on";
            signalBuffer = zeros(16 * bufferLength, 1);
            postIdx = 1:15 * bufferLength;
            preIdx = postIdx + bufferLength;
            captureIdx = 15 * bufferLength + (1:bufferLength);
            timeAxis = ((1:16 * bufferLength) - 16 * bufferLength) / fs;
            app.SignalData.totalOverrun = 0;
            axis(app.WaveformMonitor,[timeAxis(1) timeAxis(end) -1 1]);
            set(app.SpectrumMonitor, 'ylim', [0 19999]);
            %app.SignalData.wCursor
            indexWholePre = 1:length(app.SignalData.wholeWave) - bufferLength;
            updateFaxis(app);
            set(app.SignalData.lowLevelHandle, 'xdata', [1 1] * app.LowLdBSlider.Value);
            set(app.SignalData.highLevelHandle, 'xdata', ...
                min(30, [1 1] * app.LowLdBSlider.Value + app.RangedBSlider.Value));
            app.RUNLamp.Visible = 'on';
            app.OVRLamp.Visible = 'off';
            overRunCounter = 0;
            idxWin3 = circshift((1:fftl)', -3 * bufferLength/4);
            idxWin2 = circshift((1:fftl)', -2 * bufferLength/4);
            idxWin1 = circshift((1:fftl)', -bufferLength/4);
            while app.SignalData.running == "on"
                signalBuffer(postIdx) = signalBuffer(preIdx);
                [inputData,numOverrun] = app.SignalData.recorder();
                app.SignalData.totalOverrun = ...
                    app.SignalData.totalOverrun + numOverrun;
                signalBuffer(captureIdx) = inputData;
                %--- wave monitor update
                set(app.SignalData.waveHandle, 'ydata', signalBuffer);
                set(app.WaveformMonitor, 'ylim', max(abs(signalBuffer)) * [-1 1]);
                set(app.SignalData.winShapeHandle(1), 'ydata', ...
                    max(abs(signalBuffer)) * app.SignalData.wCursor(: ,1));
                set(app.SignalData.winShapeHandle(2), 'ydata', ...
                    max(abs(signalBuffer)) * app.SignalData.wCursor(: ,2));
                %--- spectrum monitor update
                powerSlice = abs(fft(signalBuffer .* app.SignalData.window)) .^ 2;
                dBspec = 10*log10(powerSlice);
                set(app.SignalData.specHandle, 'xdata', dBspec);
                set(app.SpectrumMonitor, 'xlim', [-40 80] - 50);
                %--- whole wave view update
                app.SignalData.wholeWave(indexWholePre) = ...
                    app.SignalData.wholeWave(indexWholePre + bufferLength);
                app.SignalData.wholeWave(end - bufferLength + 1:end) = ...
                    inputData;
                set(app.SignalData.wholeWaveHandle, 'ydata', app.SignalData.wholeWave);
                set(app.WaveWholeAxis, 'ylim', max(abs(app.SignalData.wholeWave)) * [-1 1]);
                %--- spectrogram view update
                ylimSpec = get(app.SpectrumMonitor, 'ylim');
                viewAxis = ylimSpec(1) + (0:499)' / 499 * diff(ylimSpec);
                lowLevel = get(app.SignalData.lowLevelHandle, 'xdata');
                highLevel = get(app.SignalData.highLevelHandle, 'xdata');
                %sgramSlice = interp1(app.SignalData.fAxis, dBspec, viewAxis);
                wtmp = app.SignalData.window;
                powerBlock = abs(fft([signalBuffer signalBuffer  signalBuffer  signalBuffer ] ...
                    .* [wtmp(idxWin3) wtmp(idxWin2) wtmp(idxWin1) wtmp])) .^ 2;
                cumPower = cumsum(powerBlock) * app.SignalData.fAxis(2);
                cumSlice = interp1(app.SignalData.fAxis, cumPower, ...
                    viewAxis - app.SignalData.fAxis(2) / 2, "linear", "extrap");
                sgramSlice = (cumSlice([2:end end], :) - cumSlice) / (viewAxis(2) - viewAxis(1));
                sgramSlice(end, :) = sgramSlice(end - 1, :);
                sgramSlice = 10 * log10(sgramSlice);
                app.SignalData.sgramView(:, 1:end-4) = app.SignalData.sgramView(:, 5:end);
                app.SignalData.sgramView(:, end-3:end) = max(0, min(255, ...
                    (sgramSlice - lowLevel(1)) / (highLevel(1) - lowLevel(1)) * 255));
                set(app.SgramAxis, 'xlim', [-3 0] - app.SignalData.wLengthInMs / 1000 / 2 );
                set(app.SignalData.sgramHandle, 'cdata', app.SignalData.sgramView);
                set(app.SignalData.sgramHandle, 'xdata', ...
                    app.SignalData.frameTimeBase - 3 - app.SignalData.wLengthInMs / 1000 / 2 );
                if app.SignalData.totalOverrun > 0 && overRunCounter == 0
                    app.OVRLamp.Visible = 'on';
                    overRunCounter = 20;
                elseif overRunCounter > 0
                    overRunCounter = overRunCounter - 1;
                    if overRunCounter == 0
                        app.SignalData.totalOverrun = 0;
                    end
                else
                    app.OVRLamp.Visible = 'off';
                end
                drawnow limitrate
            end
            release(app.SignalData.recorder);
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
        end
        
        function results = setWindowInformation(app)
                        results = [];
            fftl = app.SignalData.fftl;
            fs = app.SignalData.samplingFrequency;
            wLengthInMs = app.SignalData.wLengthInMs;
            wLength = min(fftl, round(wLengthInMs / 1000 * fs));
            switch app.TypeDropDown.Value
                case app.TypeDropDown.Items{1}
                    wTmp = ones(wLength, 1);
                    wCursor = ones(200, 1);
                case app.TypeDropDown.Items{2}
                    wTmp = bartlett(wLength);
                    wCursor = bartlett(200);
                case app.TypeDropDown.Items{3}
                    wTmp = hamming(wLength);
                    wCursor = hamming(200);
                case app.TypeDropDown.Items{4}
                    wTmp = hann(wLength);
                    wCursor = hann(200);
                case app.TypeDropDown.Items{5}
                    wTmp = blackman(wLength);
                    wCursor = blackman(200);
                case app.TypeDropDown.Items{6}
                    wTmp = nuttallwin(wLength);
                    wCursor = nuttallwin(200);
            end
            wCursorTime = ((1:200) - 200) / 200;
            app.SignalData.wCursor = [wCursor -wCursor];
            app.SignalData.wCursorTime = wCursorTime;
            wTmp = wTmp / sqrt(sum(wTmp));
            w = zeros(fftl, 1);
            w(end - wLength + 1:end) = wTmp;
            app.SignalData.window = w;
            set(app.WaveWholeAxis, 'xlim', [-3 0] - app.SignalData.wLengthInMs / 1000 / 2 );
        end
        
        function results = updateFaxis(app)
            results = [];
            panCenter = app.PANkHzSlider.Value * 1000;
            fs = app.SignalData.samplingFrequency;
            xView = [0 20000] / app.MAGSlider.Value;
            xCenter = sum(xView) / 2;
            xLow = xView(1) - xCenter + panCenter;
            xHigh = xView(2) - xCenter + panCenter;
            if xLow < 0
                yLim = xView;
            elseif xHigh > fs / 2
                yLim = xView - xView(2) + 20000;
            else
                yLim = xView - xCenter + panCenter;
            end
            set(app.SpectrumMonitor, 'ylim', min(19999,yLim));

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeAudioInput(app)
            app.STOPButton.Enable = 'off';
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            disp("BYE...")
            pause(0.5);
            close(app.UIFigure);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.SignalData.running = "off";
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            disableDefaultInteractivity(app.WaveformMonitor);
            disableDefaultInteractivity(app.SpectrumMonitor);
            disableDefaultInteractivity(app.WaveWholeAxis);
            disableDefaultInteractivity(app.SgramAxis);
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            updateLoop(app);
            enableDefaultInteractivity(app.WaveWholeAxis);
            enableDefaultInteractivity(app.SgramAxis);
            enableDefaultInteractivity(app.WaveformMonitor);
            enableDefaultInteractivity(app.SpectrumMonitor);
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
        end

        % Value changing function: WidthmsSlider
        function WidthmsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SignalData.wLengthInMs = changingValue;
            setWindowInformation(app);
            set(app.SignalData.winEndHandle, 'xdata', ...
                - changingValue / 1000 * [1 1]);
            set(app.SignalData.winShapeHandle, 'xdata', ...
                changingValue / 1000 * app.SignalData.wCursorTime);
            app.SignalData.totalOverrun = 0;
        end

        % Value changing function: MAGSlider
        function MAGSliderValueChanging(app, event)
            changingValue = event.Value;
            panCenter = app.PANkHzSlider.Value * 1000;
            %fs = app.SignalData.samplingFrequency;
            xView = [0 20000] / changingValue;
            xCenter = sum(xView) / 2;
            xLow = xView(1) - xCenter + panCenter;
            xHigh = xView(2) - xCenter + panCenter;
            if xLow < 0
                yLim = xView;
            elseif xHigh > 20000
                yLim = xView - xView(2) + 20000;
            else
                yLim = xView - xCenter + panCenter;
            end
            set(app.SpectrumMonitor, 'ylim', min(19999,yLim));
        end

        % Value changing function: PANkHzSlider
        function PANkHzSliderValueChanging(app, event)
            changingValue = event.Value;
            panCenter = changingValue * 1000;
            fs = app.SignalData.samplingFrequency;
            xView = [0 20000] / app.MAGSlider.Value;
            xCenter = sum(xView) / 2;
            xLow = xView(1) - xCenter + panCenter;
            xHigh = xView(2) - xCenter + panCenter;
            if xLow < 0
                yLim = xView;
            elseif xHigh > fs / 2
                yLim = xView - xView(2) + 20000;
            else
                yLim = xView - xCenter + panCenter;
            end
            set(app.SpectrumMonitor, 'ylim', min(19999,yLim));
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            app.SignalData.wLengthInMs = app.WidthmsSlider.Value;
            setWindowInformation(app);
            yMax = max(get(app.WaveformMonitor, 'ylim'));
            set(app.SignalData.winEndHandle, 'xdata', ...
                - app.WidthmsSlider.Value / 1000 * [1 1]);
            set(app.SignalData.winShapeHandle, 'xdata', ...
                app.WidthmsSlider.Value / 1000 * app.SignalData.wCursorTime);
            set(app.SignalData.winShapeHandle(1), 'ydata', ...
                yMax * app.SignalData.wCursor(: ,1));
            set(app.SignalData.winShapeHandle(2), 'ydata', ...
                yMax * app.SignalData.wCursor(: ,2));
            app.SignalData.totalOverrun = 0;
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            message = {'Copyright 2021 Hideki Kawahara'; ' '; ...
                'Licensed under the Apache License, Version 2.0 (the "License");'; ...
                'you may not use this file except in compliance with the License.'; ...
                'You may obtain a copy of the License at'; '  '; ...
                '    http://www.apache.org/licenses/LICENSE-2.0'; '  '; ...
                'Unless required by applicable law or agreed to in writing, software'; ...
                'distributed under the License is distributed on an "AS IS" BASIS,'; ...
                'WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.'; ...
                'See the License for the specific language governing permissions and'; ...
                'limitations under the License.'};
            msgbox(message)
        end

        % Value changing function: LowLdBSlider
        function LowLdBSliderValueChanging(app, event)
            changingValue = event.Value;
            set(app.SignalData.lowLevelHandle, 'xdata', [1 1] * changingValue);
            set(app.SignalData.highLevelHandle, 'xdata', ...
                min(30, [1 1] * changingValue + app.RangedBSlider.Value));
        end

        % Value changing function: RangedBSlider
        function RangedBSliderValueChanging(app, event)
            changingValue = event.Value;
            set(app.SignalData.highLevelHandle, 'xdata', ...
                min(30, [1 1] * changingValue + app.LowLdBSlider.Value));            
        end

        % Value changed function: CMapDropDown
        function CMapDropDownValueChanged(app, event)
            value = app.CMapDropDown.Value;
            switch value
                case 'parula'
                    colormap(app.SgramAxis, "parula");
                case 'turbo'
                    colormap(app.SgramAxis, "turbo");
                case 'gray'
                    colormap(app.SgramAxis, "gray");
                case '1-gray'
                    tmp = colormap(app.SgramAxis,"gray");
                    colormap(app.SgramAxis, 1-tmp);
                case 'hot'
                    colormap(app.SgramAxis, "hot");
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {652, 652};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {208, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.RUNLampLabel = uilabel(app.LeftPanel);
            app.RUNLampLabel.Text = 'RUN';
            app.RUNLamp = uilamp(app.LeftPanel);
            app.OVRLampLabel = uilabel(app.LeftPanel);
            app.OVRLampLabel.Text = 'OVR';
            app.OVRLamp = uilamp(app.LeftPanel);
            app.SpectrogramviewerSP3ARKLabel = uilabel(app.LeftPanel);
            app.SpectrogramviewerSP3ARKLabel.Text = {'Spectrogram'; 'viewer'; '(SP3ARK)'};
            app.TypeDropDownLabel = uilabel(app.LeftPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.LeftPanel);
            app.TypeDropDown.Items = {'rectangular', 'Bartlet', 'Hamming', 'Hanning', 'Blackman', 'Nuttall'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Blackman';
            app.AboutButton = uibutton(app.LeftPanel, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.CMapDropDownLabel = uilabel(app.LeftPanel);
            app.CMapDropDownLabel.Text = 'CMap';
            app.CMapDropDown = uidropdown(app.LeftPanel);
            app.CMapDropDown.Items = {'parula', 'turbo', 'gray', '1-gray', 'hot'};
            app.CMapDropDown.ValueChangedFcn = createCallbackFcn(app, @CMapDropDownValueChanged, true);
            app.CMapDropDown.Value = 'parula';
            app.RightPanel = uipanel(app.GridLayout);
            app.WidthmsSliderLabel = uilabel(app.RightPanel);
            app.WidthmsSliderLabel.Text = {'Width'; 'ms'};
            app.WidthmsSlider = uislider(app.RightPanel);
            app.WidthmsSlider.Limits = [30 350];
            app.WidthmsSlider.ValueChangingFcn = createCallbackFcn(app, @WidthmsSliderValueChanging, true);
            app.WidthmsSlider.Value = 350;
            app.PANkHzSliderLabel = uilabel(app.RightPanel);
            app.PANkHzSliderLabel.Text = {'PAN'; 'kHz'};
            app.PANkHzSlider = uislider(app.RightPanel);
            app.PANkHzSlider.Limits = [0 20];
            app.PANkHzSlider.ValueChangingFcn = createCallbackFcn(app, @PANkHzSliderValueChanging, true);
            app.MAGSliderLabel = uilabel(app.RightPanel);
            app.MAGSliderLabel.Text = 'MAG';
            app.MAGSlider = uislider(app.RightPanel);
            app.MAGSlider.Limits = [1 20];
            app.MAGSlider.ValueChangingFcn = createCallbackFcn(app, @MAGSliderValueChanging, true);
            app.MAGSlider.Value = 1;
            app.RangedBSliderLabel = uilabel(app.RightPanel);
            app.RangedBSliderLabel.Text = {'Range'; 'dB'};
            app.RangedBSlider = uislider(app.RightPanel);
            app.RangedBSlider.Limits = [20 120];
            app.RangedBSlider.ValueChangingFcn = createCallbackFcn(app, @RangedBSliderValueChanging, true);
            app.RangedBSlider.Value = 120;
            app.LowLdBSliderLabel = uilabel(app.RightPanel);
            app.LowLdBSliderLabel.Text = {'LowL'; 'dB'};
            app.LowLdBSlider = uislider(app.RightPanel);
            app.LowLdBSlider.Limits = [-90 30];
            app.LowLdBSlider.ValueChangingFcn = createCallbackFcn(app, @LowLdBSliderValueChanging, true);
            app.LowLdBSlider.Value = -90;
            app.WaveformMonitor = uiaxes(app.RightPanel);
            title(app.WaveformMonitor, 'waveform monitor')
            xlabel(app.WaveformMonitor, 'time (s)')
            ylabel(app.WaveformMonitor, 'amplitude')
            zlabel(app.WaveformMonitor, 'Z')
            app.WaveformMonitor.YTick = [-1 0 1];
            app.WaveformMonitor.YTickLabel = {'-1'; '0'; '1'};
            app.SpectrumMonitor = uiaxes(app.RightPanel);
            title(app.SpectrumMonitor, ' ')
            xlabel(app.SpectrumMonitor, 'X')
            ylabel(app.SpectrumMonitor, 'Y')
            zlabel(app.SpectrumMonitor, 'Z')
            app.SgramAxis = uiaxes(app.RightPanel);
            title(app.SgramAxis, '  ')
            xlabel(app.SgramAxis, 'X')
            ylabel(app.SgramAxis, 'Y')
            zlabel(app.SgramAxis, 'Z')
            app.SgramAxis.XLim = [0 0.999];
            app.WaveWholeAxis = uiaxes(app.RightPanel);
            ylabel(app.WaveWholeAxis, 'Y')
            zlabel(app.WaveWholeAxis, 'Z')
            app.WaveWholeAxis.YLim = [-0.99 0.99];
            app.WaveWholeAxis.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.WaveWholeAxis.XTickLabel = '';
            app.WaveWholeAxis.YTick = [-1 0 1];
            app.WaveWholeAxis.YTickLabel = {'-1.0'; '0.0'; '1.0'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sgramViewerTw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end