classdef waveSpecViewerTw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        LeftPanel           matlab.ui.container.Panel
        STARTButton         matlab.ui.control.Button
        STOPButton          matlab.ui.control.Button
        QUITButton          matlab.ui.control.Button
        RUNLampLabel        matlab.ui.control.Label
        RUNLamp             matlab.ui.control.Lamp
        OVRLampLabel        matlab.ui.control.Label
        OVRLamp             matlab.ui.control.Lamp
        WaveformandspectrumviewerSP3ARKLabel  matlab.ui.control.Label
        TypeDropDownLabel   matlab.ui.control.Label
        TypeDropDown        matlab.ui.control.DropDown
        AboutButton         matlab.ui.control.Button
        RightPanel          matlab.ui.container.Panel
        WidthmsSliderLabel  matlab.ui.control.Label
        WidthmsSlider       matlab.ui.control.Slider
        PANkHzSliderLabel   matlab.ui.control.Label
        PANkHzSlider        matlab.ui.control.Slider
        MAGSliderLabel      matlab.ui.control.Label
        MAGSlider           matlab.ui.control.Slider
        WaveformMonitor     matlab.ui.control.UIAxes
        SpectrumMonitor     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SignalData % Description
    end
    
    methods (Access = private)
        
        function results = initializeAudioInput(app)
            results = [];
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
            fs = 44100;
            bufferLength = 1024;
            recorderObj = audioDeviceReader;
            devices = getAudioDevices(recorderObj);
            [deviceID,tf] = listdlg('ListString',devices, 'SelectionMode','single');
            if ~tf
                close(app.UIFigure);
            else
                app.SignalData.Device = devices{deviceID};
                app.SignalData.recorder = audioDeviceReader(fs, bufferLength, "ChannelMapping", 1, "BitDepth","24-bit integer");
                app.SignalData.samplingFrequency = fs;
                app.SignalData.bufferSize = bufferLength;
                app.SignalData.running = "off";
                app.SignalData.signalBuffer = zeros(16 * bufferLength, 1);
                timeAxis = ((1:16 * bufferLength) - 16 * bufferLength) / fs;
                fftl = length(timeAxis);
                app.SignalData.waveHandle = plot(app.WaveformMonitor,timeAxis, app.SignalData.signalBuffer);
                hold(app.WaveformMonitor,'on');
                app.SignalData.winEndHandle = plot(app.WaveformMonitor, ...
                    - app.WidthmsSlider.Value / 1000 * [1 1], [-1 1] ,'g', ...
                    'linewidth', 3);
                app.SignalData.fftl = fftl;
                app.SignalData.wLengthInMs = app.WidthmsSlider.Value;
                setWindowInformation(app);
                app.SignalData.winShapeHandle = plot(app.WaveformMonitor, ...
                    app.SignalData.wCursorTime * app.WidthmsSlider.Value ...
                    / 1000, app.SignalData.wCursor, "Color", [0 1 0], "LineWidth", 3);
                hold(app.WaveformMonitor,'off');
                axis(app.WaveformMonitor,[timeAxis(1) timeAxis(end) -1 1]);
                %--- for spectrum
                
                fx = (0:fftl - 1) / fftl * fs;
                tmpY = rand(fftl, 1) - 0.5;
                w = app.SignalData.window;
                app.SignalData.specHandle = plot(app.SpectrumMonitor, fx, ...
                    20 * log10(abs(fft(tmpY .* w))));
                set(app.SpectrumMonitor, 'xlim', [0 fs / 2]);
                set(app.SpectrumMonitor, 'ylim', [-40 80] - 50);
                xlabel(app.SpectrumMonitor, 'frequency (Hz)')
                ylabel(app.SpectrumMonitor, 'level (rel. dB)')
            end
        end
        
        function results = updateLoop(app)
            results = [];
            bufferLength = app.SignalData.bufferSize;
            fs = app.SignalData.samplingFrequency;
            app.SignalData.running = "on";
            signalBuffer = zeros(16 * bufferLength, 1);
            postIdx = 1:15 * bufferLength;
            preIdx = postIdx + bufferLength;
            captureIdx = 15 * bufferLength + (1:bufferLength);
            timeAxis = ((1:16 * bufferLength) - 16 * bufferLength) / fs;
            app.SignalData.totalOverrun = 0;
            axis(app.WaveformMonitor,[timeAxis(1) timeAxis(end) -1 1]);
            set(app.SpectrumMonitor, 'xlim', [0 fs / 2]);
            %app.SignalData.wCursor
            app.RUNLamp.Visible = 'on';
            app.OVRLamp.Visible = 'off';
            while app.SignalData.running == "on"
                signalBuffer(postIdx) = signalBuffer(preIdx);
                [inputData,numOverrun] = app.SignalData.recorder();
                app.SignalData.totalOverrun = ...
                    app.SignalData.totalOverrun + numOverrun;
                signalBuffer(captureIdx) = inputData;
                set(app.SignalData.waveHandle, 'ydata', signalBuffer);
                set(app.WaveformMonitor, 'ylim', max(abs(signalBuffer)) * [-1 1]);
                set(app.SignalData.winShapeHandle(1), 'ydata', ...
                    max(abs(signalBuffer)) * app.SignalData.wCursor(: ,1));
                set(app.SignalData.winShapeHandle(2), 'ydata', ...
                    max(abs(signalBuffer)) * app.SignalData.wCursor(: ,2));
                dBspec = 20*log10(abs(fft(signalBuffer .* app.SignalData.window)));
                set(app.SignalData.specHandle, 'ydata', dBspec);
                set(app.SpectrumMonitor, 'ylim', [-40 80] - 50);
                if app.SignalData.totalOverrun > 0
                    app.OVRLamp.Visible = 'on';
                else
                    app.OVRLamp.Visible = 'off';
                end
                drawnow limitrate
            end
            release(app.SignalData.recorder);
            app.RUNLamp.Visible = 'off';
            app.OVRLamp.Visible = 'off';
        end
        
        function results = setWindowInformation(app)
                        results = [];
            fftl = app.SignalData.fftl;
            fs = app.SignalData.samplingFrequency;
            wLengthInMs = app.SignalData.wLengthInMs;
            wLength = min(fftl, round(wLengthInMs / 1000 * fs));
            switch app.TypeDropDown.Value
                case app.TypeDropDown.Items{1}
                    wTmp = ones(wLength, 1);
                    wCursor = ones(200, 1);
                case app.TypeDropDown.Items{2}
                    wTmp = bartlett(wLength);
                    wCursor = bartlett(200);
                case app.TypeDropDown.Items{3}
                    wTmp = hamming(wLength);
                    wCursor = hamming(200);
                case app.TypeDropDown.Items{4}
                    wTmp = hann(wLength);
                    wCursor = hann(200);
                case app.TypeDropDown.Items{5}
                    wTmp = blackman(wLength);
                    wCursor = blackman(200);
                case app.TypeDropDown.Items{6}
                    wTmp = nuttallwin(wLength);
                    wCursor = nuttallwin(200);
            end
            wCursorTime = ((1:200) - 200) / 200;
            app.SignalData.wCursor = [wCursor -wCursor];
            app.SignalData.wCursorTime = wCursorTime;
            wTmp = wTmp / sqrt(sum(wTmp));
            w = zeros(fftl, 1);
            w(end - wLength + 1:end) = wTmp;
            app.SignalData.window = w;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            initializeAudioInput(app)
            app.STOPButton.Enable = 'off';
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            disp("BYE...")
            pause(0.5);
            close(app.UIFigure);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.SignalData.running = "off";
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            disableDefaultInteractivity(app.WaveformMonitor);
            disableDefaultInteractivity(app.SpectrumMonitor);
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            app.QUITButton.Enable = 'off';
            updateLoop(app);
            enableDefaultInteractivity(app.WaveformMonitor);
            enableDefaultInteractivity(app.SpectrumMonitor);
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            app.QUITButton.Enable = 'on';
        end

        % Value changing function: WidthmsSlider
        function WidthmsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SignalData.wLengthInMs = changingValue;
            setWindowInformation(app);
            set(app.SignalData.winEndHandle, 'xdata', ...
                - changingValue / 1000 * [1 1]);
            set(app.SignalData.winShapeHandle, 'xdata', ...
                changingValue / 1000 * app.SignalData.wCursorTime);
            app.SignalData.totalOverrun = 0;
        end

        % Value changing function: MAGSlider
        function MAGSliderValueChanging(app, event)
            changingValue = event.Value;
            panCenter = app.PANkHzSlider.Value * 1000;
            fs = app.SignalData.samplingFrequency;
            xView = [0 fs / 2] / changingValue;
            xCenter = sum(xView) / 2;
            xLow = xView(1) - xCenter + panCenter;
            xHigh = xView(2) - xCenter + panCenter;
            if xLow < 0
                xLim = xView;
            elseif xHigh > fs / 2
                xLim = xView - xView(2) + fs / 2;
            else
                xLim = xView - xCenter + panCenter;
            end
            set(app.SpectrumMonitor, 'xlim', xLim);
        end

        % Value changing function: PANkHzSlider
        function PANkHzSliderValueChanging(app, event)
            changingValue = event.Value;
            panCenter = changingValue * 1000;
            fs = app.SignalData.samplingFrequency;
            xView = [0 fs / 2] / app.MAGSlider.Value;
            xCenter = sum(xView) / 2;
            xLow = xView(1) - xCenter + panCenter;
            xHigh = xView(2) - xCenter + panCenter;
            if xLow < 0
                xLim = xView;
            elseif xHigh > fs / 2
                xLim = xView - xView(2) + fs / 2;
            else
                xLim = xView - xCenter + panCenter;
            end
            set(app.SpectrumMonitor, 'xlim', xLim);
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            app.SignalData.wLengthInMs = app.WidthmsSlider.Value;
            setWindowInformation(app);
            yMax = max(get(app.WaveformMonitor, 'ylim'));
            set(app.SignalData.winEndHandle, 'xdata', ...
                - app.WidthmsSlider.Value / 1000 * [1 1]);
            set(app.SignalData.winShapeHandle, 'xdata', ...
                app.WidthmsSlider.Value / 1000 * app.SignalData.wCursorTime);
            set(app.SignalData.winShapeHandle(1), 'ydata', ...
                yMax * app.SignalData.wCursor(: ,1));
            set(app.SignalData.winShapeHandle(2), 'ydata', ...
                yMax * app.SignalData.wCursor(: ,2));
            app.SignalData.totalOverrun = 0;
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            message = {'Copyright 2021 Hideki Kawahara'; ' '; ...
                'Licensed under the Apache License, Version 2.0 (the "License");'; ...
                'you may not use this file except in compliance with the License.'; ...
                'You may obtain a copy of the License at'; '  '; ...
                '    http://www.apache.org/licenses/LICENSE-2.0'; '  '; ...
                'Unless required by applicable law or agreed to in writing, software'; ...
                'distributed under the License is distributed on an "AS IS" BASIS,'; ...
                'WITHOUT WARRANTIES OR CONDITIONS OF ANY KIND, either express or implied.'; ...
                'See the License for the specific language governing permissions and'; ...
                'limitations under the License.'};
            msgbox(message)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.RUNLampLabel = uilabel(app.LeftPanel);
            app.RUNLampLabel.Text = 'RUN';
            app.RUNLamp = uilamp(app.LeftPanel);
            app.OVRLampLabel = uilabel(app.LeftPanel);
            app.OVRLampLabel.Text = 'OVR';
            app.OVRLamp = uilamp(app.LeftPanel);
            app.WaveformandspectrumviewerSP3ARKLabel = uilabel(app.LeftPanel);
            app.WaveformandspectrumviewerSP3ARKLabel.Text = {'Waveform and'; 'spectrum'; 'viewer'; '(SP3ARK)'};
            app.TypeDropDownLabel = uilabel(app.LeftPanel);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.LeftPanel);
            app.TypeDropDown.Items = {'rectangular', 'Bartlet', 'Hamming', 'Hanning', 'Blackman', 'Nuttall'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Blackman';
            app.AboutButton = uibutton(app.LeftPanel, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.RightPanel = uipanel(app.GridLayout);
            app.WidthmsSliderLabel = uilabel(app.RightPanel);
            app.WidthmsSliderLabel.Text = {'Width'; 'ms'};
            app.WidthmsSlider = uislider(app.RightPanel);
            app.WidthmsSlider.Limits = [30 350];
            app.WidthmsSlider.ValueChangingFcn = createCallbackFcn(app, @WidthmsSliderValueChanging, true);
            app.WidthmsSlider.Value = 350;
            app.PANkHzSliderLabel = uilabel(app.RightPanel);
            app.PANkHzSliderLabel.Text = {'PAN'; 'kHz'};
            app.PANkHzSlider = uislider(app.RightPanel);
            app.PANkHzSlider.Limits = [0 20];
            app.PANkHzSlider.ValueChangingFcn = createCallbackFcn(app, @PANkHzSliderValueChanging, true);
            app.MAGSliderLabel = uilabel(app.RightPanel);
            app.MAGSliderLabel.Text = 'MAG';
            app.MAGSlider = uislider(app.RightPanel);
            app.MAGSlider.Limits = [1 20];
            app.MAGSlider.ValueChangingFcn = createCallbackFcn(app, @MAGSliderValueChanging, true);
            app.MAGSlider.Value = 1;
            app.WaveformMonitor = uiaxes(app.RightPanel);
            title(app.WaveformMonitor, 'waveform monitor')
            xlabel(app.WaveformMonitor, 'time (s)')
            ylabel(app.WaveformMonitor, 'amplitude')
            zlabel(app.WaveformMonitor, 'Z')
            app.WaveformMonitor.YTick = [-1 0 1];
            app.WaveformMonitor.YTickLabel = {'-1'; '0'; '1'};
            app.SpectrumMonitor = uiaxes(app.RightPanel);
            title(app.SpectrumMonitor, 'Title')
            xlabel(app.SpectrumMonitor, 'X')
            ylabel(app.SpectrumMonitor, 'Y')
            zlabel(app.SpectrumMonitor, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waveSpecViewerTw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end